classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FossilUI    matlab.ui.Figure
        FileMenu    matlab.ui.container.Menu
        AboutMenu   matlab.ui.container.Menu
        EditMenu    matlab.ui.container.Menu
        EditorPane  matlab.ui.control.TextArea
        HTMLPane    matlab.ui.control.HTML
    end

    
    properties (Access = public)
        ver = '0.1'; % current version number of the app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set Fossil UI size to screen size position
            screen_size = get(groot, "ScreenSize"); % [x, y, width, height]
            app.FossilUI.Position = screen_size;
            
            % edit positions for Editor & HTML panes, depending on screen
            % sizes. Below describes the pixel margins for positions:
            %  [x: 10 pixels from the left side of the ui,
            %   y: 20 pixels from the bottom of the ui,
            %   width: 50 pixels between both panes and sides of ui,
            %   height: 150 pixels between upper toolbar and bottom of ui.]
            app.EditorPane.Position = [10, 20, ...
                screen_size(3)/2-50, screen_size(4)-150];
            app.HTMLPane.Position = [screen_size(3)/2+10, 20, ...
                screen_size(3)/2-50, screen_size(4)-150];
            
            % set Fossil UI name to "Fossil v[version] - [/dir]"
            app.FossilUI.Name = ['Fossil v' app.ver ' - ' pwd];
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FossilUI = uifigure('Visible', 'off');
            app.FossilUI.Name = 'Fossil 0.9';
            app.FileMenu = uimenu(app.FossilUI);
            app.FileMenu.Text = 'File';
            app.AboutMenu = uimenu(app.FileMenu);
            app.AboutMenu.Text = 'About';
            app.EditMenu = uimenu(app.FossilUI);
            app.EditMenu.Text = 'Edit';
            app.EditorPane = uitextarea(app.FossilUI);
            app.HTMLPane = uihtml(app.FossilUI);
            % Show the figure after all components are created
            app.FossilUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FossilUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FossilUI)
        end
    end
end